classdef app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ApplyButton                 matlab.ui.control.Button
        OperationDropDown           matlab.ui.control.DropDown
        OperationDropDownLabel      matlab.ui.control.Label
        HistogramDisplayPanel       matlab.ui.container.Panel
        InputHistogramR             matlab.ui.control.UIAxes
        InputHistogramG             matlab.ui.control.UIAxes
        InputHistogramB             matlab.ui.control.UIAxes
        OutputHistogramR            matlab.ui.control.UIAxes
        OutputHistogramG            matlab.ui.control.UIAxes
        OutputHistogramB            matlab.ui.control.UIAxes
        ImageDisplayPanel           matlab.ui.container.Panel
        InputImage                  matlab.ui.control.UIAxes
        OutputImage                 matlab.ui.control.UIAxes
        OperationTabGroup           matlab.ui.container.TabGroup
        ConvolutionTab              matlab.ui.container.Tab
        MaskList                    matlab.ui.control.Image
        MaskDropDown                matlab.ui.control.DropDown
        MaskDropDownLabel           matlab.ui.control.Label
        ConvolutionPlot             matlab.ui.control.UIAxes
        SmoothingBlurringTab        matlab.ui.container.Tab
        LogMultiplierField_3        matlab.ui.control.NumericEditField
        MaskSzeLabel_2              matlab.ui.control.Label
        LogMultiplierField_2        matlab.ui.control.NumericEditField
        MaskSzeLabel                matlab.ui.control.Label
        TypeDropDown                matlab.ui.control.DropDown
        TypeDropDownLabel           matlab.ui.control.Label
        NegativePlot                matlab.ui.control.UIAxes
        LogTransformTab             matlab.ui.container.Tab
        LogMultiplierField          matlab.ui.control.NumericEditField
        MultiplierEditFieldLabel_2  matlab.ui.control.Label
        LogPlot                     matlab.ui.control.UIAxes
        PowerTransformTab           matlab.ui.container.Tab
        PowerMultiplierField        matlab.ui.control.NumericEditField
        MultiplierEditFieldLabel_3  matlab.ui.control.Label
        PowerGammaField             matlab.ui.control.NumericEditField
        GammaLabel                  matlab.ui.control.Label
        PowerPlot                   matlab.ui.control.UIAxes
        ContrastStretchingTab       matlab.ui.container.Tab
        CStretchingMinRField        matlab.ui.control.NumericEditField
        CStretchingMinGField        matlab.ui.control.NumericEditField
        CStretchingMinBField        matlab.ui.control.NumericEditField
        CStretchingMaxRField        matlab.ui.control.NumericEditField
        MinimumLabel_6              matlab.ui.control.Label
        CStretchingMaxGField        matlab.ui.control.NumericEditField
        CStretchingMaxBField        matlab.ui.control.NumericEditField
        MinimumLabel_5              matlab.ui.control.Label
        MinimumLabel_4              matlab.ui.control.Label
        MinimumLabel_3              matlab.ui.control.Label
        MinimumLabel_2              matlab.ui.control.Label
        MinimumLabel                matlab.ui.control.Label
        HistogramEqualizationTab    matlab.ui.container.Tab
        MappingPlot                 matlab.ui.control.UIAxes
        HistogramMatchingTab        matlab.ui.container.Tab
        ReferenceImageSelectLabel   matlab.ui.control.Label
        ReferenceImageSelectButton  matlab.ui.control.Button
        ReferenceImage              matlab.ui.control.UIAxes
        ReferenceHistogramR         matlab.ui.control.UIAxes
        ReferenceHistogramG         matlab.ui.control.UIAxes
        ReferenceHistogramB         matlab.ui.control.UIAxes
        SelectImageButton           matlab.ui.control.Button
        SelectedImageLabel          matlab.ui.control.Label
        ChooseImageLabel            matlab.ui.control.Label
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        pathToFile (1,1) string % Path to the currently loaded image file.
        pathToRefFile (1,1) string % Path to the reference image used for histogram specification.
    end
    
    
    methods (Access = private)
        
        function [Iout] = readImage(~, path)
            [Iout, palette] = imread(path);

            if ~isempty(palette)
                [M, N, ~] = size(Iout);
                C = size(palette, 2);
                IoutNonindexed = zeros(M, N, C);
                
                for i = 1 : M
                    for j = 1 : N
                        IoutNonindexed(i, j, :) = uint8(palette(Iout(i, j, 1) + 1, :) * 255);
                    end
                end

                Iout = IoutNonindexed;
                Iout = uint8(Iout);
            end
        end

        function [] = clearDisplays(comp)
            cla(comp.InputImage);
            cla(comp.OutputImage);

            cla(comp.InputHistogramR);
            cla(comp.InputHistogramG);
            cla(comp.InputHistogramB);

            cla(comp.OutputHistogramR);
            cla(comp.OutputHistogramG);
            cla(comp.OutputHistogramB);
        end

        function [] = displayHistogram(~, dispHist, axesR, axesG, axesB)
            cla(axesR);
            cla(axesG);
            cla(axesB);

            if size(dispHist, 1) == 1
                rCol = 'k';
                axesG.Visible = 'off';
                axesB.Visible = 'off';
            else
                rCol = 'r';
                axesG.Visible = 'on';
                axesB.Visible = 'on';
            end

            bar(axesR, 0:255, dispHist(1, :), 'FaceColor', rCol);
            if size(dispHist, 1) > 1
                bar(axesG, 0:255, dispHist(2, :), 'FaceColor', 'g');
                bar(axesB, 0:255, dispHist(3, :), 'FaceColor', 'b');
            end
        end

        function [] = displayInputHistogram(comp, dispHist)
            displayHistogram(comp, dispHist, comp.InputHistogramR, comp.InputHistogramG, comp.InputHistogramB);
        end

        function [] = displayOutputHistogram(comp, dispHist)
            displayHistogram(comp, dispHist, comp.OutputHistogramR, comp.OutputHistogramG, comp.OutputHistogramB);
        end

        function [] = brightnessRefreshPlot(comp)
            a = comp.BrightnessAField.Value;
            b = comp.BrightnessBField.Value;

            fnHandle = str2func(['@(x)min(max(', num2str(a), '*x+', num2str(b), ', 0), 255)']);
            plot(comp.ConvolutionPlot, 0:255, fnHandle(0:255));
        end
        
        function [] = negativeRefreshPlot(comp)
            if comp.NegativeEnableCheckBox.Value == 1
                fnHandle = @(x)255 - x;
            else
                fnHandle = @(x)x;
            end

            plot(comp.NegativePlot, 0:255, fnHandle(0:255));
        end

        function [] = logRefreshPlot(comp)
            c = comp.LogMultiplierField.Value;

            fnHandle = @(x)min(max(c * log10(double(1 + x)), 0), 255);
            plot(comp.LogPlot, 0:255, fnHandle(0:255));
        end

        function [] = powerRefreshPlot(comp)
            c = comp.PowerMultiplierField.Value;
            g = comp.PowerGammaField.Value;

            fnHandle = @(x)min(max(c * x .^ g, 0), 255);
            plot(comp.PowerPlot, 0:255, fnHandle(0:255));
        end

        function [] = equalizationShowPlot(comp, mapping)
            cla(comp.MappingPlot);
            plot(comp.MappingPlot, 0:255, mapping);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            brightnessRefreshPlot(comp);
            negativeRefreshPlot(comp);
            logRefreshPlot(comp);
            powerRefreshPlot(comp);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(comp, event)
            [fileName, fileLoc] = uigetfile({'*.bmp;*.tif;*.tiff', 'All files'; '*.bmp', 'BMP files'; '*.tif;*.tiff', 'TIFF files'});
            if fileLoc ~= 0
                clearDisplays(comp);

                comp.pathToFile = join([fileLoc, fileName], '\');
                comp.SelectedImageLabel.Text = comp.pathToFile;
                I = readImage(comp, comp.pathToFile);
                imshow(I, 'Parent', comp.InputImage);
    
                inputHist = make_histogram(I);
                displayInputHistogram(comp, inputHist);

                comp.ApplyButton.Enable = comp.pathToFile ~= '';
            end
        end

        % Selection change function: OperationTabGroup
        function OperationTabGroupSelectionChanged(comp, event)
            comp.OperationTabGroup.SelectedTab = event.OldValue;
        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(comp, event)
            comp.ApplyButton.Enable = comp.pathToFile ~= '';
            switch comp.OperationDropDown.Value
                case 'Brightness'
                    comp.OperationTabGroup.SelectedTab = comp.ConvolutionTab;
                case 'Negative'
                    comp.OperationTabGroup.SelectedTab = comp.SmoothingBlurringTab;
                case 'Logarithm transform'
                    comp.OperationTabGroup.SelectedTab = comp.LogTransformTab;
                case 'Power transform'
                    comp.OperationTabGroup.SelectedTab = comp.PowerTransformTab;
                case 'Contrast stretching'
                    comp.OperationTabGroup.SelectedTab = comp.ContrastStretchingTab;
                case 'Histogram equalization'
                    comp.OperationTabGroup.SelectedTab = comp.HistogramEqualizationTab;
                case 'Histogram matching'
                    comp.OperationTabGroup.SelectedTab = comp.HistogramMatchingTab;
                    comp.ApplyButton.Enable = comp.pathToFile ~= '' & comp.pathToRefFile ~= '';
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(comp, event)
            I = readImage(comp, comp.pathToFile);

            switch comp.OperationDropDown.Value
                case 'Brightness'
                    a = comp.BrightnessAField.Value;
                    b = comp.BrightnessBField.Value;
                    I2 = brightening(I, a, b);
                case 'Negative'
                    I2 = I;
                    if comp.NegativeEnableCheckBox.Value
                        I2 = negative(I2);
                    end
                case 'Logarithm transform'
                    c = comp.LogMultiplierField.Value;
                    I2 = logarithm(I, c);
                case 'Power transform'
                    c = comp.PowerMultiplierField.Value;
                    g = comp.PowerGammaField.Value;
                    I2 = power_gamma(I, c, g);
                case 'Contrast stretching'
                    [I2, minVal, maxVal] = contrast_stretching(I);
                    comp.CStretchingMinRField.Value = minVal(1);
                    comp.CStretchingMaxRField.Value = maxVal(1);
                    if size(minVal, 1) > 1
                        comp.CStretchingMinGField.Value = minVal(2);
                        comp.CStretchingMinBField.Value = minVal(3);

                        comp.CStretchingMaxGField.Value = maxVal(2);
                        comp.CStretchingMaxBField.Value = maxVal(3);

                        comp.CStretchingMinGField.Visible = 'on';
                        comp.CStretchingMinBField.Visible = 'on';
                        comp.CStretchingMaxGField.Visible = 'on';
                        comp.CStretchingMaxBField.Visible = 'on';
                    else
                        comp.CStretchingMinGField.Visible = 'off';
                        comp.CStretchingMinBField.Visible = 'off';
                        comp.CStretchingMaxGField.Visible = 'off';
                        comp.CStretchingMaxBField.Visible = 'off';
                    end
                case 'Histogram equalization'
                    [I2, mapping] = hist_equalization(I, 0);
                    equalizationShowPlot(comp, mapping);
                case 'Histogram matching'
                    Iref = readImage(comp, comp.pathToRefFile);
                    I2 = hist_specification(I, Iref);
            end

            outHist = make_histogram(I2);
            displayOutputHistogram(comp, outHist);
            imshow(I2, 'Parent', comp.OutputImage);
        end

        % Callback function
        function BrightnessAFieldValueChanged(comp, event)
            brightnessRefreshPlot(comp);
        end

        % Callback function
        function BrightnessBFieldValueChanged(comp, event)
            brightnessRefreshPlot(comp);
        end

        % Callback function
        function NegativeEnableCheckBoxValueChanged(comp, event)
            negativeRefreshPlot(comp);
        end

        % Value changed function: LogMultiplierField
        function LogMultiplierFieldValueChanged(comp, event)
            logRefreshPlot(comp);
        end

        % Value changed function: PowerMultiplierField
        function PowerMultiplierFieldValueChanged(comp, event)
            powerRefreshPlot(comp);
        end

        % Value changed function: PowerGammaField
        function PowerGammaFieldValueChanged(comp, event)
            powerRefreshPlot(comp);
        end

        % Button pushed function: ReferenceImageSelectButton
        function ReferenceImageSelectButtonPushed(comp, event)
            [fileName, fileLoc] = uigetfile({'*.bmp;*.tif;*.tiff', 'All files'; '*.bmp', 'BMP files'; '*.tif;*.tiff', 'TIFF files'});
            if fileLoc ~= 0
                cla(comp.ReferenceHistogramR);
                cla(comp.ReferenceHistogramG);
                cla(comp.ReferenceHistogramB);

                comp.pathToRefFile = join([fileLoc, fileName], '\');
                I = readImage(comp, comp.pathToRefFile);
                imshow(I, 'Parent', comp.ReferenceImage);

                refHist = make_histogram(I);
                displayHistogram(comp, refHist, comp.ReferenceHistogramR, comp.ReferenceHistogramG, comp.ReferenceHistogramB);

                comp.ApplyButton.Enable = comp.pathToFile ~= '' & comp.pathToRefFile ~= '';
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 1343 601];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.ChooseImageLabel = uilabel(comp);
            comp.ChooseImageLabel.Text = 'Choose image';
            comp.SelectedImageLabel = uilabel(comp);
            comp.SelectedImageLabel.Enable = 'off';
            comp.SelectedImageLabel.Text = 'No image selected';
            comp.SelectImageButton = uibutton(comp, 'push');
            comp.SelectImageButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectImageButtonPushed, true);
            comp.SelectImageButton.Text = 'Select';
            comp.OperationTabGroup = uitabgroup(comp);
            comp.OperationTabGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @OperationTabGroupSelectionChanged, true);
            comp.ConvolutionTab = uitab(comp.OperationTabGroup);
            comp.ConvolutionTab.Title = 'Convolution';
            comp.ConvolutionPlot = uiaxes(comp.ConvolutionTab);
            title(comp.ConvolutionPlot, 'Level mapping')
            xlabel(comp.ConvolutionPlot, 'Before')
            ylabel(comp.ConvolutionPlot, 'After')
            comp.ConvolutionPlot.XLim = [0 255];
            comp.ConvolutionPlot.YLim = [0 255];
            comp.ConvolutionPlot.ZLim = [0 1];
            comp.ConvolutionPlot.XTick = [0 63 127 191 255];
            comp.ConvolutionPlot.XTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.ConvolutionPlot.YTick = [0 63 127 191 255];
            comp.ConvolutionPlot.YTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.ConvolutionPlot.ZTick = [];
            comp.ConvolutionPlot.ZTickLabel = '';
            comp.MaskDropDownLabel = uilabel(comp.ConvolutionTab);
            comp.MaskDropDownLabel.Text = 'Mask';
            comp.MaskDropDown = uidropdown(comp.ConvolutionTab);
            comp.MaskDropDown.Items = {'Mask 1', 'Mask 2', 'Mask 3', 'Mask 4'};
            comp.MaskDropDown.Value = 'Mask 3';
            comp.MaskList = uiimage(comp.ConvolutionTab);
            comp.MaskList.ImageSource = fullfile(pathToMLAPP, 'convolution_mask.png');
            comp.SmoothingBlurringTab = uitab(comp.OperationTabGroup);
            comp.SmoothingBlurringTab.Title = 'Smoothing / Blurring';
            comp.NegativePlot = uiaxes(comp.SmoothingBlurringTab);
            title(comp.NegativePlot, 'Level mapping')
            xlabel(comp.NegativePlot, 'Before')
            ylabel(comp.NegativePlot, 'After')
            comp.NegativePlot.XLim = [0 255];
            comp.NegativePlot.YLim = [0 255];
            comp.NegativePlot.ZLim = [0 1];
            comp.NegativePlot.XTick = [0 63 127 191 255];
            comp.NegativePlot.XTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.NegativePlot.YTick = [0 63 127 191 255];
            comp.NegativePlot.YTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.NegativePlot.ZTick = [];
            comp.NegativePlot.ZTickLabel = '';
            comp.TypeDropDownLabel = uilabel(comp.SmoothingBlurringTab);
            comp.TypeDropDownLabel.Text = 'Type';
            comp.TypeDropDown = uidropdown(comp.SmoothingBlurringTab);
            comp.TypeDropDown.Items = {'Mean Filter', 'Gaussian Filter', 'ILPF', 'BLPF', 'GLPF', 'IHPF', 'BHPF', 'GHPF'};
            comp.TypeDropDown.Value = 'Gaussian Filter';
            comp.MaskSzeLabel = uilabel(comp.SmoothingBlurringTab);
            comp.MaskSzeLabel.Text = 'Mask Size';
            comp.LogMultiplierField_2 = uieditfield(comp.SmoothingBlurringTab, 'numeric');
            comp.LogMultiplierField_2.Limits = [0 Inf];
            comp.LogMultiplierField_2.Value = 106;
            comp.MaskSzeLabel_2 = uilabel(comp.SmoothingBlurringTab);
            comp.MaskSzeLabel_2.Text = 'D0';
            comp.LogMultiplierField_3 = uieditfield(comp.SmoothingBlurringTab, 'numeric');
            comp.LogMultiplierField_3.Limits = [0 Inf];
            comp.LogMultiplierField_3.Value = 106;
            comp.LogTransformTab = uitab(comp.OperationTabGroup);
            comp.LogTransformTab.Title = 'Logarithm';
            comp.LogPlot = uiaxes(comp.LogTransformTab);
            title(comp.LogPlot, 'Level mapping')
            xlabel(comp.LogPlot, 'Before')
            ylabel(comp.LogPlot, 'After')
            comp.LogPlot.XLim = [0 255];
            comp.LogPlot.YLim = [0 255];
            comp.LogPlot.ZLim = [0 1];
            comp.LogPlot.XTick = [0 63 127 191 255];
            comp.LogPlot.XTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.LogPlot.YTick = [0 63 127 191 255];
            comp.LogPlot.YTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.LogPlot.ZTick = [];
            comp.LogPlot.ZTickLabel = '';
            comp.MultiplierEditFieldLabel_2 = uilabel(comp.LogTransformTab);
            comp.MultiplierEditFieldLabel_2.Text = 'Multiplier';
            comp.LogMultiplierField = uieditfield(comp.LogTransformTab, 'numeric');
            comp.LogMultiplierField.Limits = [0 Inf];
            comp.LogMultiplierField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LogMultiplierFieldValueChanged, true);
            comp.LogMultiplierField.Value = 106;
            comp.PowerTransformTab = uitab(comp.OperationTabGroup);
            comp.PowerTransformTab.Title = 'Power';
            comp.PowerPlot = uiaxes(comp.PowerTransformTab);
            title(comp.PowerPlot, 'Level mapping')
            xlabel(comp.PowerPlot, 'Before')
            ylabel(comp.PowerPlot, 'After')
            comp.PowerPlot.XLim = [0 255];
            comp.PowerPlot.YLim = [0 255];
            comp.PowerPlot.ZLim = [0 1];
            comp.PowerPlot.XTick = [0 63 127 191 255];
            comp.PowerPlot.XTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.PowerPlot.YTick = [0 63 127 191 255];
            comp.PowerPlot.YTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.PowerPlot.ZTick = [];
            comp.PowerPlot.ZTickLabel = '';
            comp.GammaLabel = uilabel(comp.PowerTransformTab);
            comp.GammaLabel.Text = 'Gamma';
            comp.PowerGammaField = uieditfield(comp.PowerTransformTab, 'numeric');
            comp.PowerGammaField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PowerGammaFieldValueChanged, true);
            comp.PowerGammaField.Value = 1;
            comp.MultiplierEditFieldLabel_3 = uilabel(comp.PowerTransformTab);
            comp.MultiplierEditFieldLabel_3.Text = 'Multiplier';
            comp.PowerMultiplierField = uieditfield(comp.PowerTransformTab, 'numeric');
            comp.PowerMultiplierField.Limits = [0 Inf];
            comp.PowerMultiplierField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PowerMultiplierFieldValueChanged, true);
            comp.PowerMultiplierField.Value = 1;
            comp.ContrastStretchingTab = uitab(comp.OperationTabGroup);
            comp.ContrastStretchingTab.Title = 'Contrast stretching';
            comp.MinimumLabel = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel.Text = 'Minimum (R)';
            comp.MinimumLabel_2 = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel_2.Text = 'Minimum (G)';
            comp.MinimumLabel_3 = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel_3.Text = 'Minimum (B)';
            comp.MinimumLabel_4 = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel_4.Text = 'Maximum (R)';
            comp.MinimumLabel_5 = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel_5.Text = 'Maximum (G)';
            comp.CStretchingMaxBField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMaxBField.Limits = [0 255];
            comp.CStretchingMaxBField.RoundFractionalValues = 'on';
            comp.CStretchingMaxBField.Editable = 'off';
            comp.CStretchingMaxGField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMaxGField.Limits = [0 255];
            comp.CStretchingMaxGField.RoundFractionalValues = 'on';
            comp.CStretchingMaxGField.Editable = 'off';
            comp.MinimumLabel_6 = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel_6.Text = 'Maximum (B)';
            comp.CStretchingMaxRField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMaxRField.Limits = [0 255];
            comp.CStretchingMaxRField.RoundFractionalValues = 'on';
            comp.CStretchingMaxRField.Editable = 'off';
            comp.CStretchingMinBField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMinBField.Limits = [0 255];
            comp.CStretchingMinBField.RoundFractionalValues = 'on';
            comp.CStretchingMinBField.Editable = 'off';
            comp.CStretchingMinGField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMinGField.Limits = [0 255];
            comp.CStretchingMinGField.RoundFractionalValues = 'on';
            comp.CStretchingMinGField.Editable = 'off';
            comp.CStretchingMinRField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMinRField.Limits = [0 255];
            comp.CStretchingMinRField.RoundFractionalValues = 'on';
            comp.CStretchingMinRField.Editable = 'off';
            comp.HistogramEqualizationTab = uitab(comp.OperationTabGroup);
            comp.HistogramEqualizationTab.Title = 'Histogram equalization';
            comp.MappingPlot = uiaxes(comp.HistogramEqualizationTab);
            title(comp.MappingPlot, 'Level mapping')
            xlabel(comp.MappingPlot, 'Before')
            ylabel(comp.MappingPlot, 'After')
            comp.MappingPlot.XLim = [0 255];
            comp.MappingPlot.YLim = [0 255];
            comp.MappingPlot.ZLim = [0 1];
            comp.MappingPlot.XTick = [0 63 127 191 255];
            comp.MappingPlot.XTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.MappingPlot.YTick = [0 63 127 191 255];
            comp.MappingPlot.YTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.MappingPlot.ZTick = [];
            comp.MappingPlot.ZTickLabel = '';
            comp.HistogramMatchingTab = uitab(comp.OperationTabGroup);
            comp.HistogramMatchingTab.Title = 'Histogram matching';
            comp.ReferenceHistogramB = uiaxes(comp.HistogramMatchingTab);
            title(comp.ReferenceHistogramB, 'Reference histogram (B)')
            xlabel(comp.ReferenceHistogramB, 'Intensity')
            ylabel(comp.ReferenceHistogramB, 'Frequency')
            comp.ReferenceHistogramG = uiaxes(comp.HistogramMatchingTab);
            title(comp.ReferenceHistogramG, 'Reference histogram (G)')
            xlabel(comp.ReferenceHistogramG, 'Intensity')
            ylabel(comp.ReferenceHistogramG, 'Frequency')
            comp.ReferenceHistogramR = uiaxes(comp.HistogramMatchingTab);
            title(comp.ReferenceHistogramR, 'Reference histogram (R)')
            xlabel(comp.ReferenceHistogramR, 'Intensity')
            ylabel(comp.ReferenceHistogramR, 'Frequency')
            comp.ReferenceImage = uiaxes(comp.HistogramMatchingTab);
            title(comp.ReferenceImage, 'Reference image')
            comp.ReferenceImage.XTick = [];
            comp.ReferenceImage.YTick = [];
            comp.ReferenceImageSelectButton = uibutton(comp.HistogramMatchingTab, 'push');
            comp.ReferenceImageSelectButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ReferenceImageSelectButtonPushed, true);
            comp.ReferenceImageSelectButton.Text = 'Select';
            comp.ReferenceImageSelectLabel = uilabel(comp.HistogramMatchingTab);
            comp.ReferenceImageSelectLabel.Text = 'Reference image';
            comp.ImageDisplayPanel = uipanel(comp);
            comp.ImageDisplayPanel.Title = 'Image display';
            comp.OutputImage = uiaxes(comp.ImageDisplayPanel);
            title(comp.OutputImage, 'Output image')
            comp.OutputImage.XTick = [];
            comp.OutputImage.YTick = [];
            comp.InputImage = uiaxes(comp.ImageDisplayPanel);
            title(comp.InputImage, 'Input image')
            comp.InputImage.XTick = [];
            comp.InputImage.YTick = [];
            comp.HistogramDisplayPanel = uipanel(comp);
            comp.HistogramDisplayPanel.Title = 'Histogram display';
            comp.OutputHistogramB = uiaxes(comp.HistogramDisplayPanel);
            title(comp.OutputHistogramB, 'Output histogram (B)')
            xlabel(comp.OutputHistogramB, 'Intensity')
            ylabel(comp.OutputHistogramB, 'Frequency')
            comp.OutputHistogramB.XLim = [0 255];
            comp.OutputHistogramG = uiaxes(comp.HistogramDisplayPanel);
            title(comp.OutputHistogramG, 'Output histogram (G)')
            xlabel(comp.OutputHistogramG, 'Intensity')
            ylabel(comp.OutputHistogramG, 'Frequency')
            comp.OutputHistogramG.XLim = [0 255];
            comp.OutputHistogramR = uiaxes(comp.HistogramDisplayPanel);
            title(comp.OutputHistogramR, 'Output histogram (R)')
            xlabel(comp.OutputHistogramR, 'Intensity')
            ylabel(comp.OutputHistogramR, 'Frequency')
            comp.OutputHistogramR.XLim = [0 255];
            comp.InputHistogramB = uiaxes(comp.HistogramDisplayPanel);
            title(comp.InputHistogramB, 'Input histogram (B)')
            xlabel(comp.InputHistogramB, 'Intensity')
            ylabel(comp.InputHistogramB, 'Frequency')
            comp.InputHistogramB.XLim = [0 255];
            comp.InputHistogramG = uiaxes(comp.HistogramDisplayPanel);
            title(comp.InputHistogramG, 'Input histogram (G)')
            xlabel(comp.InputHistogramG, 'Intensity')
            ylabel(comp.InputHistogramG, 'Frequency')
            comp.InputHistogramG.XLim = [0 255];
            comp.InputHistogramR = uiaxes(comp.HistogramDisplayPanel);
            title(comp.InputHistogramR, 'Input histogram (R)')
            xlabel(comp.InputHistogramR, 'Intensity')
            ylabel(comp.InputHistogramR, 'Frequency')
            comp.InputHistogramR.XLim = [0 255];
            comp.OperationDropDownLabel = uilabel(comp);
            comp.OperationDropDownLabel.Text = 'Operation';
            comp.OperationDropDown = uidropdown(comp);
            comp.OperationDropDown.Items = {'Brightness', 'Negative', 'Logarithm transform', 'Power transform', 'Contrast stretching', 'Histogram equalization', 'Histogram matching'};
            comp.OperationDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @OperationDropDownValueChanged, true);
            comp.OperationDropDown.Value = 'Brightness';
            comp.ApplyButton = uibutton(comp, 'push');
            comp.ApplyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ApplyButtonPushed, true);
            comp.ApplyButton.Enable = 'off';
            comp.ApplyButton.Text = 'Apply';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end